classdef findLaserPoints < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Label   matlab.ui.control.Label
        UIAxes  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        points
        x = 1;
        y = 1;
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            global lsr
            data = lsr.data;

            % Set up some variables for later
            comp.points = [];
            comp.x = 1;
            comp.y = 1;

            % Display first image
            img = imshow(data{1,1}, 'Parent', comp.UIAxes);
            comp.UIAxes.PickableParts = 'all';
            img.HitTest = 'off';
            
        end

        % Button down function: UIAxes
        function ImageClicked(comp, event)
            % Get current point
            coordinates = get(comp.UIAxes,'CurrentPoint');
            point = drawpoint('Position',coordinates(1,1:2), 'MarkerSize', 5, 'Parent', comp.UIAxes);
            point_coord = get(point, 'position');
            
            % Get galvo data
            global lsr
            GalvoVoltage = lsr.GalvoVoltage;

            % Save current point and galvo data
            galvoVolt = GalvoVoltage{comp.x,comp.y};
            gx = galvoVolt(1,1);
            gy = galvoVolt(1,2);
            insert = [point_coord(1,1) point_coord(1,2) gx gy];
            comp.points= [comp.points; insert];

            % Set new image
            data = lsr.data;
            maxx = size(data, 1);
            maxy = size(data,2);

            willclose = 0;
            if comp.y == maxy
                if comp.x == maxx
                    % Save translated_coords as file in directory
                    all_points = comp.points;
                    filename = sprintf('cal_mtx_%s.mat', datetime('now','Format','MMddyy'));
                    save(filename, 'all_points');

                    % Quit GUI
                    willclose = 1;
                    closereq();

                else
                    comp.y = 1;
                    comp.x = comp.x + 1;
                end

            else
                comp.y = comp.y + 1;
            end
            
            if ~willclose
                img = imshow(data{comp.x, comp.y}, 'Parent', comp.UIAxes);
                img.HitTest = 'off';
            end

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 635 393];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @ImageClicked, true);
            comp.Label = uilabel(comp);
            comp.Label.Text = 'With your mouse, click the location of the laser. Try to get in the very center of the laser.';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end